classdef SeriesTable < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        Panel          matlab.ui.container.Panel
        ProceedButton  matlab.ui.control.Button
        UITable        matlab.ui.control.Table
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, tabledata)
            app.UITable.Data = tabledata;
            colnams = tabledata.Properties.VariableNames;
            colnams = strrep(colnams,'Image','');
            colnams{7} = 'SliceSpacing';
            app.UITable.ColumnName = colnams';
            drawnow;
            app.UIFigure.WindowState = 'maximized';
        end

        % Button pushed function: ProceedButton
        function ProceedButtonPushed(app, event)
            uiresume(app.UIFigure);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'CHECKED series will be used for T1rho calculation - Pick only ONE series for analysis';
            app.ProceedButton = uibutton(app.Panel, 'push');
            app.ProceedButton.ButtonPushedFcn = createCallbackFcn(app, @ProceedButtonPushed, true);
            app.ProceedButton.Text = 'Proceed';
            app.UITable = uitable(app.Panel);
            app.UITable.ColumnName = {''};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SeriesTable(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end